classdef led_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        TIME_STEP = 0.07 % fixed time step of calling SendData function
        % define colors
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        s % serial port
        t % timer (for update GUI and send data)
        led_state = 0 % knobs value
        color_plot % color plot handlers
    end
    
    methods (Access = private)
        
        function updateGUI(app, led_state)
            % plot color value in GUI
            app.color_plot.YData = [app.color_plot.YData(2:end) led_state];
            % change color of GUI lamp
            app.LEDLamp.Color = [0 led_state 0]; % scale color values to 0..1 range
        end
        
        function sendData(app, led_state)
            if ~app.Simulate.Value
                % if output buffer is empty write values to serial port in asyncronous mode
                if app.s.BytesToOutput == 0
                    value_to_send = uint8(led_state); % scale color values to 255..0 uint8 range
                    fwrite(app.s, value_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
                end
            end
        end
        
        
        function timerFunction(app, ~, ~)
            % Timer callback
            app.updateGUI(app.led_state);
            app.sendData(app.led_state);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up swith values
            app.LEDSwitch.ItemsData = [0 1];
            
            % read knob values into 1x3 array
            app.led_state = app.LEDSwitch.Value;
            % define time vector for plotting
            time = (0 : app.TIME_STEP : 10)';
            
            % init and set up axes
            app.Axes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Axes);
            
            % define color order fot plotting
            app.Axes.ColorOrder = app.GREEN_COLOR;
            
            app.color_plot = plot(app.Axes, time, zeros(length(time), 1), 'LineWidth', 2);
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.timerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Callback function
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                if ~app.Simulate.Value
                    stopasync(app.s)
                    fclose(app.s);
                end
                app.ConnLamp.Color = app.RED_COLOR;
                app.Simulate.Enable = 'on';
            end
        end

        % Callback function
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"
                if ~app.Simulate.Value
                    app.s = serial(app.PortList.Value);
                    fopen(app.s);
                end
                start(app.t);
                app.ConnLamp.Color = app.GREEN_COLOR;
                app.Simulate.Enable = 'off';
            end
        end

        % Callback function
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Callback function
        function SimulateValueChanged(app, event)
            app.BDisconButtonPushed();
            value = app.Simulate.Value;
            app.HardwarePanel.Visible = ~value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app);
        end

        % Callback function
        function HelpButtonPushed(app, event)
            open led_doc.mlx
        end

        % Callback function
        function LEDSwitchValueChanged(app, event)
            app.led_state = app.LEDSwitch.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'LED Control';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = led_control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end