classdef mainControllerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ControllingAndTelemetryTab      matlab.ui.container.Tab
        ModeChooser                     matlab.ui.container.ButtonGroup
        ControlElevationManuallyButton  matlab.ui.control.RadioButton
        ControlAzimuthManuallyButton    matlab.ui.control.RadioButton
        HomingElevationButton           matlab.ui.control.RadioButton
        HomingAzimuthButton             matlab.ui.control.RadioButton
        TrackWithGPredictButton         matlab.ui.control.RadioButton
        Homing2axesButton               matlab.ui.control.RadioButton
        Control2axesButton              matlab.ui.control.RadioButton
        ControlElevationButton          matlab.ui.control.RadioButton
        ControlAzimuthButton            matlab.ui.control.RadioButton
        DeploymentLabel                 matlab.ui.control.Label
        TuneLabel                       matlab.ui.control.Label
        HomingLabel                     matlab.ui.control.Label
        HandycontrollingLabel           matlab.ui.control.Label
        DebugPanel                      matlab.ui.container.Panel
        CounterForCommand               matlab.ui.control.NumericEditField
        RXLabel                         matlab.ui.control.Label
        DebugTextBox                    matlab.ui.control.EditField
        TXLabel                         matlab.ui.control.Label
        DebugTextBox_2                  matlab.ui.control.EditField
        ConectivityPanel                matlab.ui.container.Panel
        OpenPort                        matlab.ui.control.Button
        ShutDownPort                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PortList                        matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        StationstatusPanel              matlab.ui.container.Panel
        ElevationAngle                  matlab.ui.control.Slider
        AzimuthAngle                    matlab.ui.control.Slider
        ElevationAngleBox               matlab.ui.control.NumericEditField
        AzimuthAngleBox                 matlab.ui.control.NumericEditField
        ElevationAngleGaugeLabel        matlab.ui.control.Label
        ElevationAngleGauge             matlab.ui.control.SemicircularGauge
        AzimuthAngleGaugeLabel          matlab.ui.control.Label
        AzimuthAngleGauge               matlab.ui.control.SemicircularGauge
        EndstopElevationLabel           matlab.ui.control.Label
        EndStopEl                       matlab.ui.control.Lamp
        EndstopAzimuthLabel             matlab.ui.control.Label
        EndStopAz                       matlab.ui.control.Lamp
        InputElevationAngle             matlab.ui.control.NumericEditField
        InputAzimuthAngle               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        Button_2                        matlab.ui.control.Button
        InformationTab                  matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
    end


    properties (Access = private)
        myTimer % Description
        s % Serial port
        ElDesiredAngle % Input El angle
        AzDesiredAngle % Input Az angle
        ElCurrentAngle % El angle in GS
        AzCurrentAngle % Az angle in GS
        time            % time array
        t               % timer (for update GUI and send data)
        t_2s;
        myTimer_2
        myTimer_3
        timeBaseCounter_1 = 0;
        timeBaseCounter_2 = 0;
        logoVGU;
        c % value from GND string [ el, elCurrent, az, azCurrent, endstop el, endstop az, current MODE   ]
        time_step = 0.1; % fixed time step of calling SendData function
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
        GRAY_COLOR = [0.4706 0.5059 0.4706];
        str= "";
        readStringFromGND = "";
        modeSentToGS = 4;
        
        MODE_TEST_1 = 0;
        MODE_TEST_2 = 1;
        MODE_HOMING_EL_MANUALLY_BOTH_AUTO = 2;
        MODE_PC_EL = 7;
        MODE_PC_AZ = 8;
        MODE_PC_DEBUG = 9;
        MODE_HOMING_EL_MANUALLY = 4;
        MODE_HOMING_AZ_MANUALLY = 5;

        MODE_GPREDICT = 6;
        stringSentToGS="";
        
        timeAccum = 0;
        currentEleAccum = 0;
        currentAzAccum = 0;
        desiredEleAccum = 0;
        desiredAzAccum = 0;
        
        MAX_DISP = 400;
        
    end

    methods (Access = private)
    
        function sendElDesiredAngle(app)
             if app.s.BytesToOutput == 0
              %  current_values = 0.5 * current_values; % decrease maximum brightness to prevent your eyes burning
              %  values_to_send = uint8(2.55 * (100 - current_values)); % scale color values to 255..0 uint8 range
                fwrite(app.s, values_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
            end
            
        end


        function  ReadData(app)
           
          % app.DebugTextBox_2.Value = char(48);
            %app.light_value = double(fread(app.s, 1, 'uint16')) ;
            %{
            readFromSerial = fread(app.s, 1, 'uint8');
          
            %app.ElevationAngleBox.Value = readFromSerial;
            if readFromSerial == 10
                
                 app.DebugTextBox_2.Value = str;
                str = "";
                
                %str = str + char(readFromSerial);
            
            else
               str = strcat(str,char(readFromSerial))
            end  
                %app.ElevationAngleBox.Value = fread(app.s, 1, 'uint8');
            
        end
            %}
           %  disp('Reading from GS');
          %  app.readStringFromGND = fscanf(app.s);
            
            %ElCurrentAngle = str2double(fscanf(app.s));
            %app.ElevationAngleBox.Value = ElCurrentAngle;
            %app.GaugeElevation.Value = ElCurrentAngle;
            
            %ss = "el,12,az,1212,1,1,1";
            
            app.DebugTextBox.Value = app.readStringFromGND;
          
            app.c = "";
            app.c = strsplit(app.readStringFromGND,',');


            if (app.c(1) == "el") && (app.c(3) == "az") 
            
                app.DebugTextBox.Value =string(app.c(1));
                app.DebugNumBox.Value = str2double( string( app.c(2)));
                app.ElevationAngleGauge.Value = str2double( string( app.c(2)));
                app.AzimuthAngleGauge.Value = str2double( string( app.c(4)));
            end
            
           
            %if str2num(app. c(5)) == 0 
            %    app.EndStopEl.Color = app.GREEN_COLOR;
            %else
            %    app.EndStopEl.Color = app.GREEN_RED;
            %end
        end     
    
    
        function UpdateCounter(app)
            app.timeBaseCounter_1 = app.timeBaseCounter_1+1;
            
        end
        
    
        function SendModeAndCommandToGS(app)
            
            if app.timeBaseCounter_2 > 500
                app.timeBaseCounter_2 = 0;
            else
                app.timeBaseCounter_2 = app.timeBaseCounter_2 + 0.1;
            end
            
            app.CounterForCommand.Value = app.timeBaseCounter_2;


            %disp(app.timeAccum);
            app.stringSentToGS = strcat("*", ...                            
                            num2str(app.modeSentToGS), ...
                            num2str(round(app.ElevationAngle.Value,2)* 100,'%05g'), ...
                            num2str(round(app.AzimuthAngle.Value,2)* 100, '%05g'), ...
                             "1", ...
                                "1", ...
                            "#");
                        
             app.DebugTextBox_2.Value = app.stringSentToGS;
            %num2str(round(app.ElevationAngle.Value,2)* 100);
             if get(app.s,{'Status'}) ~= "closed"            
               
                fprintf(app.s,app.stringSentToGS);
                %app.DebugTextBox.Value =  (fgets(app.s));
             end
        end

        function ReceiveFromGS(app)
             if get(app.s,{'Status'}) ~= "closed"            
             
               app.readStringFromGND =  (fgets(app.s));


                app.DebugTextBox.Value = app.readStringFromGND;
          
            app.c = "";
            app.c = strsplit(app.readStringFromGND,',');
            
            %el,0.00,az,0.00,END,END,HM,HM,MODE
          
            if (app.c(1) == "el") && (app.c(3) == "az") 


                %app.DebugNumBox.Value = str2double( string( app.c(2)));
                
                app.ElevationAngleGauge.Value = str2double( string( app.c(2)));
                app.ElevationAngleBox.Value = str2double( string( app.c(2)));
                
                app.AzimuthAngleGauge.Value = str2double( string( app.c(4)));
                app.AzimuthAngleBox.Value = str2double( string( app.c(4)));
                
                if app.c(5) == "1" 
                    app.EndStopEl.Color = app.RED_COLOR;
                else
                    app.EndStopEl.Color = app.GREEN_COLOR; % being at home!!!!! ELEND = 0;
                end
                
                
                if app.c(6) == "1" 
                    app.EndStopAz.Color = app.RED_COLOR;
                else
                    app.EndStopAz.Color = app.GREEN_COLOR;
                end
            
              % update time array
            if length(app.timeAccum) >= app.MAX_DISP
                app.timeAccum = app.timeAccum(2: app.MAX_DISP);
            end
            app.timeAccum = [app.timeAccum   app.timeBaseCounter_2];
            %disp(app.timeAccum);
            
            
             if length(app.currentEleAccum) >= app.MAX_DISP
                app.currentEleAccum = app.currentEleAccum( 2 : app.MAX_DISP);
            end
            app.currentEleAccum = [app.currentEleAccum str2double(string( app.c(2)))];
            
            
            if length(app.currentAzAccum) >= app.MAX_DISP
                app.currentAzAccum = app.currentAzAccum( 2 : app.MAX_DISP);
            end
            app.currentAzAccum = [app.currentAzAccum str2double(string( app.c(4)))];
            
            
            if length(app.desiredEleAccum) >= app.MAX_DISP
                app.desiredEleAccum = app.desiredEleAccum( 2 : app.MAX_DISP);
            end
            app.desiredEleAccum = [app.desiredEleAccum round(app.ElevationAngle.Value,2)];
            
            if length(app.desiredAzAccum) >= app.MAX_DISP
                app.desiredAzAccum = app.desiredAzAccum( 2 : app.MAX_DISP);
            end
            app.desiredAzAccum = [app.desiredAzAccum round(app.AzimuthAngle.Value,2)];


                 % plot light sensor values as an area in GUI
            %area(app.UIAxes, app.timeAccum, app.currentEleAccum,...
            %    'FaceColor', 'yellow', 'FaceAlpha', 0.5, 'EdgeColor', 'yellow');
            %hold(app.Axes, 'on'); % freeze current axes
            % plot colors values in GUI
        %    if (str2double(app.c(9)) == app.MODE_PC_EL) || (str2double(app.c(9)) == app.MODE_PC_AZ) 
            plot(app.UIAxes,app.timeAccum, app.currentEleAccum, ...        
                            '-b', ...
                            app.timeAccum, app.currentAzAccum, ...
                            '-r', ...
                            app.timeAccum, app.desiredEleAccum, ...                           
                            '--b', ...
                            app.timeAccum, app.desiredAzAccum, ...                             
                            '--r', ...
                            'LineWidth', 2);
            %plot(app.UIAxes,app.timeAccum, app.currentAzAccum, 'LineWidth', 2);
            %drawnow;
            %hold(app.UIAxes, 'off'); % unfreeze axes
            %disp(app.timeAccum);
            xlim(app.UIAxes, [min(app.timeAccum)   max(app.timeAccum)]);
            %0,0.1,0.2,0.30000000000000004,0.4,0.5,0.6000000000000001,0.7000000000000001,0.8,0.9,1
         %   end
            end


             end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.myTimer = timer('Period', 1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.myTimer.TimerFcn = @(~,~) app.UpdateCounter;
            start(app.myTimer)
            
            app.myTimer_2 = timer('Period',0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.myTimer_2.TimerFcn = @(~,~) app.SendModeAndCommandToGS;
            start(app.myTimer_2)
            
            app.myTimer_3 = timer('Period',0.1, 'ExecutionMode', 'fixedSpacing', 'TasksToExecute', Inf);
            app.myTimer_3.TimerFcn = @(~,~)app.ReceiveFromGS ;
            start(app.myTimer_3)
            
            app.Lamp.Color = app.GRAY_COLOR;
            
            % set up timer
            %app.t = timer;
            %app.t.TimerFcn = @(~, ~)app.SendData;
            %app.t.ExecutionMode = 'fixedRate';
            %app.t.Period = app.time_step;
            %app.t.BusyMode = 'drop';
            
            
            % looking for available serial ports 
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            
            %app.UIAxes = uiaxes(app.UIFigure);
            %app.UIAxes.Position = [52 313 560 99];
          
            %imshow('vguNon.png','parent',app.UIAxes);
            
        end

        % Value changed function: ElevationAngle
        function ElevationAngleValueChanged(app, event)
           app.InputElevationAngle.Value = round(app.ElevationAngle.Value,2); 
            %value = app.ElevationAngle.Value;
            %plot(app.UIAxes, value*peaks)
            %app.UIAxes.YLim = [-1000 1000];
            %plot(app.UIAxes, app.ElevationAngle.Value)
            %app.GaugeElevation.Value = app.ElevationAngle.Value;
            %app.ElevationAngleBox.NumericEditField = app.ElevationAngle.Value;
            
            %app.DebugTextBox.Value = num2str(round(app.ElevationAngle.Value,2));
            %fprintf(app.s, app.DebugTextBox.Value);
            
           % fwrite(app.s, round(app.ElevationAngle.Value,2), 'float', 'async')
  
            
        end

        % Button pushed function: OpenPort
        function OpenPortPushed(app, event)
            %serialPort = serial(app.TextArea,'BAUD',9600);
            %fopen(serialPort);
            
                 app.s = serial(app.PortList.Value);
                if get(app.s,{'Status'}) == "closed"
               
                % call ReadData function if buffer contains uint16 (2 bytes)
                %app.s.BytesAvailableFcnCount = 5;
                %app.s.BytesAvailableFcnMode = 'terminator';
                %app.s.BytesAvailableFcn = @(~,~) app.ReadData;
                
                fopen(app.s);
                set(app.s,'timeout',1);
               % pause(1);
                
                 app.Lamp.Color = app.GREEN_COLOR;
                end
               
                %app.ElevationAngleBox.Value = 12.1 ;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %stop(app.t);
            
           if get(app.s,{'Status'}) == "open"
               stopasync(app.s)
               fclose(app.s);
              
           end
           
            delete(app)
            
        end

        % Button pushed function: ShutDownPort
        function ShutDownPortButtonPushed(app, event)
              % on disconnect stop timer, close serial port and change lamp color to red
         
            if get(app.s,{'Status'}) == "open"
               stopasync(app.s)
               fclose(app.s);
               app.Lamp.Color = app.RED_COLOR;
            end
                
          
        end

        % Selection changed function: ModeChooser
        function ModeChooserSelectionChanged(app, event)
            selectedButton = app.ModeChooser.SelectedObject;
            %if selectedButton == app.ControlElevationManuallyButton
            %    app.ModeSentToGS = app.MODE_TEST1
            %else
            %    app.DebugTextBox_3.Value = '0000';
            %end
         %  disp(selectedButton)
           switch selectedButton
                case app.ControlElevationManuallyButton
                   app.modeSentToGS = app.MODE_TEST_1;
                case app.ControlAzimuthManuallyButton
                   app.modeSentToGS = app.MODE_TEST_2;
                case app.HomingElevationButton
                   app.modeSentToGS = app.MODE_HOMING_EL_MANUALLY;
                case app.HomingAzimuthButton
                   app.modeSentToGS = app.MODE_HOMING_AZ_MANUALLY;
                case app.TrackWithGPredictButton
                   app.modeSentToGS = app.MODE_GPREDICT;
                case app.Homing2axesButton
                   app.modeSentToGS = app.MODE_HOMING_EL_MANUALLY_BOTH_AUTO;
                case app.Control2axesButton
                   app.modeSentToGS = app.MODE_PC_DEBUG;
               case app.ControlElevationButton
                   app.modeSentToGS = app.MODE_PC_EL;    
               case app.ControlAzimuthButton
                   app.modeSentToGS = app.MODE_PC_AZ;                    
               
              % otherwise app.ModeSentToGS = app.MODE_PC_DEBUG; 
           end


        end

        % Value changed function: InputElevationAngle
        function InputElevationAngleValueChanged(app, event)
            value = app.InputElevationAngle.Value;
           app.ElevationAngle.Value = value;
        end

        % Value changed function: InputAzimuthAngle
        function InputAzimuthAngleValueChanged(app, event)
            value = app.InputAzimuthAngle.Value;
            app.ElevationAngle.Value = value;
        end

        % Value changed function: AzimuthAngle
        function AzimuthAngleValueChanged(app, event)
           
             app.InputAzimuthAngle.Value = round(app.AzimuthAngle.Value,2); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControllingAndTelemetryTab = uitab(app.TabGroup);
            app.ControllingAndTelemetryTab.Title = 'Controlling And Telemetry';
            app.ModeChooser = uibuttongroup(app.ControllingAndTelemetryTab);
            app.ModeChooser.SelectionChangedFcn = createCallbackFcn(app, @ModeChooserSelectionChanged, true);
            app.ModeChooser.Title = 'Mode';
            app.ControlElevationManuallyButton = uiradiobutton(app.ModeChooser);
            app.ControlElevationManuallyButton.Text = 'Control Elevation Manually';
            app.ControlAzimuthManuallyButton = uiradiobutton(app.ModeChooser);
            app.ControlAzimuthManuallyButton.Text = 'Control Azimuth Manually';
            app.HomingElevationButton = uiradiobutton(app.ModeChooser);
            app.HomingElevationButton.Text = 'Homing Elevation';
            app.HomingElevationButton.Value = true;
            app.HomingAzimuthButton = uiradiobutton(app.ModeChooser);
            app.HomingAzimuthButton.Text = 'Homing Azimuth';
            app.TrackWithGPredictButton = uiradiobutton(app.ModeChooser);
            app.TrackWithGPredictButton.Text = 'Track With GPredict';
            app.Homing2axesButton = uiradiobutton(app.ModeChooser);
            app.Homing2axesButton.Text = 'Homing 2 axes';
            app.Control2axesButton = uiradiobutton(app.ModeChooser);
            app.Control2axesButton.Text = 'Control 2 axes';
            app.ControlElevationButton = uiradiobutton(app.ModeChooser);
            app.ControlElevationButton.Text = 'Control Elevation';
            app.ControlAzimuthButton = uiradiobutton(app.ModeChooser);
            app.ControlAzimuthButton.Text = 'Control Azimuth';
            app.DeploymentLabel = uilabel(app.ModeChooser);
            app.DeploymentLabel.Text = 'Deployment:';
            app.TuneLabel = uilabel(app.ModeChooser);
            app.TuneLabel.Text = 'Tune:';
            app.HomingLabel = uilabel(app.ModeChooser);
            app.HomingLabel.Text = 'Homing:';
            app.HandycontrollingLabel = uilabel(app.ModeChooser);
            app.HandycontrollingLabel.Text = 'Handy controlling:';
            app.DebugPanel = uipanel(app.ControllingAndTelemetryTab);
            app.DebugPanel.Title = 'Debug';
            app.CounterForCommand = uieditfield(app.DebugPanel, 'numeric');
            app.CounterForCommand.Editable = 'off';
            app.CounterForCommand.Enable = 'off';
            app.RXLabel = uilabel(app.DebugPanel);
            app.RXLabel.Enable = 'off';
            app.RXLabel.Text = 'RX';
            app.DebugTextBox = uieditfield(app.DebugPanel, 'text');
            app.DebugTextBox.Editable = 'off';
            app.DebugTextBox.Enable = 'off';
            app.TXLabel = uilabel(app.DebugPanel);
            app.TXLabel.Enable = 'off';
            app.TXLabel.Text = 'TX';
            app.DebugTextBox_2 = uieditfield(app.DebugPanel, 'text');
            app.DebugTextBox_2.Editable = 'off';
            app.DebugTextBox_2.Enable = 'off';
            app.ConectivityPanel = uipanel(app.ControllingAndTelemetryTab);
            app.ConectivityPanel.Title = 'Conectivity';
            app.OpenPort = uibutton(app.ConectivityPanel, 'push');
            app.OpenPort.ButtonPushedFcn = createCallbackFcn(app, @OpenPortPushed, true);
            app.OpenPort.Text = 'Open';
            app.ShutDownPort = uibutton(app.ConectivityPanel, 'push');
            app.ShutDownPort.ButtonPushedFcn = createCallbackFcn(app, @ShutDownPortButtonPushed, true);
            app.ShutDownPort.Text = 'Shutdown';
            app.Label = uilabel(app.ConectivityPanel);
            app.Label.Text = '';
            app.PortList = uidropdown(app.ConectivityPanel);
            app.PortList.Value = 'Option 2';
            app.Label_2 = uilabel(app.ConectivityPanel);
            app.Label_2.Text = '';
            app.Lamp = uilamp(app.ConectivityPanel);
            app.StationstatusPanel = uipanel(app.ControllingAndTelemetryTab);
            app.StationstatusPanel.Title = 'Station status';
            app.ElevationAngle = uislider(app.StationstatusPanel);
            app.ElevationAngle.Limits = [0 90];
            app.ElevationAngle.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.ElevationAngle.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleValueChanged, true);
            app.AzimuthAngle = uislider(app.StationstatusPanel);
            app.AzimuthAngle.Limits = [0 360];
            app.AzimuthAngle.MajorTicks = [0 90 180 270 360];
            app.AzimuthAngle.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleValueChanged, true);
            app.ElevationAngleBox = uieditfield(app.StationstatusPanel, 'numeric');
            app.ElevationAngleBox.Editable = 'off';
            app.ElevationAngleBox.Enable = 'off';
            app.AzimuthAngleBox = uieditfield(app.StationstatusPanel, 'numeric');
            app.AzimuthAngleBox.Editable = 'off';
            app.AzimuthAngleBox.Enable = 'off';
            app.ElevationAngleGaugeLabel = uilabel(app.StationstatusPanel);
            app.ElevationAngleGaugeLabel.Text = 'Elevation Angle';
            app.ElevationAngleGauge = uigauge(app.StationstatusPanel, 'semicircular');
            app.ElevationAngleGauge.Limits = [0 90];
            app.ElevationAngleGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90];
            app.AzimuthAngleGaugeLabel = uilabel(app.StationstatusPanel);
            app.AzimuthAngleGaugeLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleGauge = uigauge(app.StationstatusPanel, 'semicircular');
            app.AzimuthAngleGauge.Limits = [0 360];
            app.AzimuthAngleGauge.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.EndstopElevationLabel = uilabel(app.StationstatusPanel);
            app.EndstopElevationLabel.Text = 'Endstop Elevation';
            app.EndStopEl = uilamp(app.StationstatusPanel);
            app.EndstopAzimuthLabel = uilabel(app.StationstatusPanel);
            app.EndstopAzimuthLabel.Text = 'Endstop Azimuth';
            app.EndStopAz = uilamp(app.StationstatusPanel);
            app.InputElevationAngle = uieditfield(app.StationstatusPanel, 'numeric');
            app.InputElevationAngle.ValueChangedFcn = createCallbackFcn(app, @InputElevationAngleValueChanged, true);
            app.InputAzimuthAngle = uieditfield(app.StationstatusPanel, 'numeric');
            app.InputAzimuthAngle.ValueChangedFcn = createCallbackFcn(app, @InputAzimuthAngleValueChanged, true);
            app.UIAxes = uiaxes(app.ControllingAndTelemetryTab);
            title(app.UIAxes, 'Plotting System Response')
            xlabel(app.UIAxes, 'Time (Second)')
            ylabel(app.UIAxes, 'Angle (Degree)')
            app.Button_2 = uibutton(app.ControllingAndTelemetryTab, 'push');
            app.Button_2.Icon = 'vguNonBG.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.Button = uibutton(app.InformationTab, 'push');
            app.Button.Icon = 'vguNonBG.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainControllerApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end